classdef AWGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigTab                       matlab.ui.container.Tab
        PingTestPanel                   matlab.ui.container.Panel
        PingServerButton                matlab.ui.control.Button
        PingTestLamp                    matlab.ui.control.Lamp
        PingTestErrorLabel              matlab.ui.control.Label
        PingAWGPanel                    matlab.ui.container.Panel
        PingAWGButton                   matlab.ui.control.Button
        PingAWGLamp                     matlab.ui.control.Lamp
        PingAWGErrorLabel               matlab.ui.control.Label
        LogTab                          matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.EditField
        SendButton                      matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        UploadTraceTab                  matlab.ui.container.Tab
        LocalFilesPanel                 matlab.ui.container.Panel
        uploadLocalErrorConsole         matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        SelectfilesButton               matlab.ui.control.Button
        RemoteFilesPanel                matlab.ui.container.Panel
        Tree                            matlab.ui.container.Tree
        uploadAWGErrorConsole           matlab.ui.control.TextArea
        ChannelSelectionDropDownLabel   matlab.ui.control.Label
        ChannelSelectionDropDown        matlab.ui.control.DropDown
        SendtoAWGButton                 matlab.ui.control.Button
        ClockTab                        matlab.ui.container.Tab
        ReferenceclockfrequencySpinnerLabel  matlab.ui.control.Label
        ReferenceclockfrequencySpinner  matlab.ui.control.Spinner
        SamplerateSpinnerLabel          matlab.ui.control.Label
        SamplerateSpinner               matlab.ui.control.Spinner
        OutputsTab                      matlab.ui.container.Tab
        startButton                     matlab.ui.control.StateButton
        stopButton                      matlab.ui.control.StateButton
        OutputstateCheckBox1            matlab.ui.control.CheckBox
        Channel1Label                   matlab.ui.control.Label
        OutputstateCheckBox2            matlab.ui.control.CheckBox
        Channel2Label                   matlab.ui.control.Label
        OutputstateCheckBox3            matlab.ui.control.CheckBox
        Channel3Label                   matlab.ui.control.Label
        OutputstateCheckBox4            matlab.ui.control.CheckBox
        Channel4Label                   matlab.ui.control.Label
        AmplitudeLabel                  matlab.ui.control.Label
        AmplitudeSpinner1               matlab.ui.control.Spinner
        AmplitudeLabel_2                matlab.ui.control.Label
        AmplitudeSpinner2               matlab.ui.control.Spinner
        AmplitudeLabel_3                matlab.ui.control.Label
        AmplitudeSpinner3               matlab.ui.control.Spinner
        AmplitudeLabel_4                matlab.ui.control.Label
        AmplitudeSpinner4               matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        AWGadd = 'TCPIP0::localhost::inst1::INSTR' % VISA TCP address of AWG
    end
    
    methods (Access = private)
        
        function refreshStatus(app)% reads current settings from the AWG to updates GUI objects
            % read clock status
            %[status, commandOut] = system('python pingTest.py');
            %if status==1
                
            %end
            
            % read output status
            
            
            % read amplitude status
            
        end
        
        function refreshAWGlog(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            PingServerButtonPushed(app)
        end

        % Button pushed function: PingServerButton
        function PingServerButtonPushed(app, event)
        app.PingTestErrorLabel.Text = "pinging";
        app.PingTestErrorLabel.FontColor=[0.93,0.69,0.13];
        app.PingTestLamp.Color = [0.93,0.69,0.13];
        drawnow
        
        [status, commandOut] = system('python pingTest.py');
            if status==2
                uialert(app.UIFigure,"python files not found add them using...","startup error")
            end
            if status==0
                app.PingTestLamp.Color=[0.0,1.0,0.0];
                app.PingTestErrorLabel.FontColor=[0.0,1.0,0.0];
            else
                app.PingTestLamp.Color=[1.0,0.0,0.0];
                app.PingTestErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            app.PingTestErrorLabel.Text = commandOut;
        end

        % Button pushed function: PingAWGButton
        function PingAWGButtonPushed(app, event)
            app.PingAWGErrorLabel.Text = "pinging";
            app.PingAWGErrorLabel.FontColor=[0.93,0.69,0.13];
            app.PingAWGLamp.Color = [0.93,0.69,0.13];
            drawnow
            
            [status, commandOut] = system('python query.py TCPIP0::localhost::inst1::INSTR *IDN?');
            
            if status==0
                app.PingAWGLamp.Color=[0.0,1.0,0.0];
                app.PingAWGErrorLabel.FontColor=[0.0,1.0,0.0];
            else
                app.PingAWGLamp.Color=[1.0,0.0,0.0];
                app.PingAWGErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            
            app.PingAWGErrorLabel.Text = commandOut;
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            app.uploadLocalErrorConsole.Value="";
            [files,path] = uigetfile('.txt','Select File(s)','MultiSelect', 'on');
            app.UIFigure.Visible = 'off'; % work around for matlab minimising app
            app.UIFigure.Visible = 'on'; % idk why this is nessisary
            if path==0 % no files selected/ box closed
                return
            end
            if ~iscell(files)
                files={files};
            end
            % check for spaces in filepath
            
            % loop through selected files
            for file = files
                % check for spaces in filename
                
                app.Label.Text="Uploading "+file;
                drawnow
                comStr="python transferFile.py " + path + file + " " + file;
                [status,response] = system(comStr);
                % check status flag
                if status==0
                    for i=1:length(app.Tree.Children)
                        if strcmp(app.Tree.Children(i).Text,file)
                            app.Tree.Children(i).delete;
                            break
                        end
                    end
                    uitreenode(app.Tree,'Text',file{1});
                else
                    app.uploadLocalErrorConsole.Value = app.uploadLocalErrorConsole.Value{1} + string(response);
                end
            end
            app.Label.Text="";
        end

        % Value changed function: OutputstateCheckBox1
        function OutputstateCheckBox1ValueChanged(app, event)
            value = app.OutputstateCheckBox1.Value;
            if value
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP1 on');
            else 
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP1 off');
            end
            refreshStatus(app);
        end

        % Value changed function: OutputstateCheckBox2
        function OutputstateCheckBox2ValueChanged(app, event)
            value = app.OutputstateCheckBox2.Value;
            if value
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP2 on');
            else 
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP2 off');
            end
            refreshStatus(app);
        end

        % Value changed function: OutputstateCheckBox3
        function OutputstateCheckBox3ValueChanged(app, event)
            value = app.OutputstateCheckBox3.Value;
            if value
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP3 on');
            else 
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP3 off');
            end
            refreshStatus(app);
        end

        % Value changed function: OutputstateCheckBox4
        function OutputstateCheckBox4ValueChanged(app, event)
            value = app.OutputstateCheckBox4.Value;
            if value
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP4 on');
            else 
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :OUTP4 off');
            end
            refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner1
        function AmplitudeEditField1ValueChanged(app, event)
            value = app.AmplitudeSpinner1.Value;
            comStr='python write.py TCPIP0::localhost::inst1::INSTR :VOLT1 '+string(value);
            [~,~] = system(comStr);
            refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner2
        function AmplitudeEditField2ValueChanged(app, event)
            value = app.AmplitudeSpinner2.Value;
            comStr='python write.py TCPIP0::localhost::inst1::INSTR :VOLT2 '+string(value);
            [~,~] = system(comStr);
            refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner3
        function AmplitudeEditField3ValueChanged(app, event)
            value = app.AmplitudeSpinner3.Value;
            comStr='python write.py TCPIP0::localhost::inst1::INSTR :VOLT3 '+string(value);
            [~,~] = system(comStr);
            refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner4
        function AmplitudeEditField4ValueChanged(app, event)
            value = app.AmplitudeSpinner4.Value;
            comStr='python write.py TCPIP0::localhost::inst1::INSTR :VOLT4 '+string(value);
            [~,~] = system(comStr);
            refreshStatus(app);
        end

        % Value changed function: ReferenceclockfrequencySpinner
        function ReferenceclockfrequencySpinnerValueChanged(app, event)
            value = app.ReferenceclockfrequencySpinner.Value;
            comStr='python write.py TCPIP0::localhost::inst1::INSTR :ROSC:FREQ '+string(value);
            [~,~] = system(comStr);
            refreshStatus(app);
        end

        % Value changed function: SamplerateSpinner
        function SamplerateSpinnerValueChanged(app, event)
            value = app.SamplerateSpinner.Value;
            comStr='python write.py TCPIP0::localhost::inst1::INSTR :FREQ:RAST '+string(value);
            [~,~] = system(comStr);
            refreshStatus(app);
        end

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            value = app.startButton.Value;
            if value
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :INIT:IMM');
            end
            app.stopButton.Value=0;
            refreshStatus(app);
        end

        % Value changed function: stopButton
        function stopButtonValueChanged(app, event)
            value = app.stopButton.Value;
            if value
                [~,~] = system('python write.py TCPIP0::localhost::inst1::INSTR :ABOR');
            end
            app.startButton.Value=0;
            refreshStatus(app);
        end

        % Button pushed function: SendtoAWGButton
        function SendtoAWGButtonPushed(app, event)
            app.uploadAWGErrorConsole.Value="";
            if isempty(app.Tree.SelectedNodes)
                app.uploadAWGErrorConsole.Value="UI error: select file from above";
                return
            end
            filename=app.Tree.SelectedNodes.Text;
            drawnow
            refreshAWGlog(app);
            comStr="python uploadFile.py " + filename + " " + app.ChannelSelectionDropDown.Value;
            [status,response] = system(comStr);
            % check status flag
            if status~=0
                app.uploadAWGErrorConsole.Value="server error: " + response;
            else
                comStr="python query.py "+app.AWGadd+" :SYST:ERR?";
                [status,response] = system(comStr);
                if status==0
                    app.uploadAWGErrorConsole.Value="AWG error: " + response;
                end
            end
            % check error log
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.PingTestPanel = uipanel(app.ConfigTab);
            app.PingServerButton = uibutton(app.PingTestPanel, 'push');
            app.PingServerButton.ButtonPushedFcn = createCallbackFcn(app, @PingServerButtonPushed, true);
            app.PingServerButton.Text = 'Ping Server';
            app.PingTestLamp = uilamp(app.PingTestPanel);
            app.PingTestErrorLabel = uilabel(app.PingTestPanel);
            app.PingTestErrorLabel.Text = {''; ''};
            app.PingAWGPanel = uipanel(app.ConfigTab);
            app.PingAWGButton = uibutton(app.PingAWGPanel, 'push');
            app.PingAWGButton.ButtonPushedFcn = createCallbackFcn(app, @PingAWGButtonPushed, true);
            app.PingAWGButton.Text = 'Ping AWG';
            app.PingAWGLamp = uilamp(app.PingAWGPanel);
            app.PingAWGErrorLabel = uilabel(app.PingAWGPanel);
            app.PingAWGErrorLabel.Text = '';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.AutoRefreshCheckBox = uicheckbox(app.LogTab);
            app.AutoRefreshCheckBox.Text = 'Auto Refresh';
            app.EditField = uieditfield(app.LogTab, 'text');
            app.EditField.Value = 'Manual prompt';
            app.SendButton = uibutton(app.LogTab, 'push');
            app.SendButton.Text = 'Send';
            app.RefreshButton = uibutton(app.LogTab, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.UploadTraceTab = uitab(app.TabGroup);
            app.UploadTraceTab.Title = 'Upload Trace';
            app.LocalFilesPanel = uipanel(app.UploadTraceTab);
            app.LocalFilesPanel.Title = 'Local Files';
            app.uploadLocalErrorConsole = uitextarea(app.LocalFilesPanel);
            app.uploadLocalErrorConsole.Editable = 'off';
            app.Label = uilabel(app.LocalFilesPanel);
            app.Label.Text = '';
            app.SelectfilesButton = uibutton(app.LocalFilesPanel, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select file(s)';
            app.RemoteFilesPanel = uipanel(app.UploadTraceTab);
            app.RemoteFilesPanel.Title = 'Remote Files';
            app.Tree = uitree(app.RemoteFilesPanel);
            app.uploadAWGErrorConsole = uitextarea(app.RemoteFilesPanel);
            app.uploadAWGErrorConsole.Editable = 'off';
            app.ChannelSelectionDropDownLabel = uilabel(app.RemoteFilesPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.RemoteFilesPanel);
            app.ChannelSelectionDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelSelectionDropDown.Value = '1';
            app.SendtoAWGButton = uibutton(app.RemoteFilesPanel, 'push');
            app.SendtoAWGButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoAWGButtonPushed, true);
            app.SendtoAWGButton.Text = 'Send to AWG';
            app.ClockTab = uitab(app.TabGroup);
            app.ClockTab.Title = 'Clock';
            app.ReferenceclockfrequencySpinnerLabel = uilabel(app.ClockTab);
            app.ReferenceclockfrequencySpinnerLabel.Text = 'Reference clock frequency';
            app.ReferenceclockfrequencySpinner = uispinner(app.ClockTab);
            app.ReferenceclockfrequencySpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceclockfrequencySpinnerValueChanged, true);
            app.ReferenceclockfrequencySpinner.Value = 100000000;
            app.SamplerateSpinnerLabel = uilabel(app.ClockTab);
            app.SamplerateSpinnerLabel.Text = 'Sample rate';
            app.SamplerateSpinner = uispinner(app.ClockTab);
            app.SamplerateSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplerateSpinnerValueChanged, true);
            app.SamplerateSpinner.Value = 620000;
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.startButton = uibutton(app.OutputsTab, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Text = 'start';
            app.stopButton = uibutton(app.OutputsTab, 'state');
            app.stopButton.ValueChangedFcn = createCallbackFcn(app, @stopButtonValueChanged, true);
            app.stopButton.Text = 'stop';
            app.OutputstateCheckBox1 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox1.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox1ValueChanged, true);
            app.OutputstateCheckBox1.Text = 'Output state';
            app.Channel1Label = uilabel(app.OutputsTab);
            app.Channel1Label.Text = 'Channel 1';
            app.OutputstateCheckBox2 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox2.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox2ValueChanged, true);
            app.OutputstateCheckBox2.Text = 'Output state';
            app.Channel2Label = uilabel(app.OutputsTab);
            app.Channel2Label.Text = 'Channel 2';
            app.OutputstateCheckBox3 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox3.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox3ValueChanged, true);
            app.OutputstateCheckBox3.Text = 'Output state';
            app.Channel3Label = uilabel(app.OutputsTab);
            app.Channel3Label.Text = 'Channel 3';
            app.OutputstateCheckBox4 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox4.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox4ValueChanged, true);
            app.OutputstateCheckBox4.Text = 'Output state';
            app.Channel4Label = uilabel(app.OutputsTab);
            app.Channel4Label.Text = 'Channel 4';
            app.AmplitudeLabel = uilabel(app.OutputsTab);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.AmplitudeSpinner1 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner1.Step = 0.025;
            app.AmplitudeSpinner1.Limits = [0.075 1];
            app.AmplitudeSpinner1.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField1ValueChanged, true);
            app.AmplitudeSpinner1.Value = 0.5;
            app.AmplitudeLabel_2 = uilabel(app.OutputsTab);
            app.AmplitudeLabel_2.Text = 'Amplitude';
            app.AmplitudeSpinner2 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner2.Step = 0.025;
            app.AmplitudeSpinner2.Limits = [0.075 1];
            app.AmplitudeSpinner2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField2ValueChanged, true);
            app.AmplitudeSpinner2.Value = 0.5;
            app.AmplitudeLabel_3 = uilabel(app.OutputsTab);
            app.AmplitudeLabel_3.Text = 'Amplitude';
            app.AmplitudeSpinner3 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner3.Step = 0.025;
            app.AmplitudeSpinner3.Limits = [0.075 1];
            app.AmplitudeSpinner3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField3ValueChanged, true);
            app.AmplitudeSpinner3.Value = 0.5;
            app.AmplitudeLabel_4 = uilabel(app.OutputsTab);
            app.AmplitudeLabel_4.Text = 'Amplitude';
            app.AmplitudeSpinner4 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner4.Step = 0.025;
            app.AmplitudeSpinner4.Limits = [0.075 1];
            app.AmplitudeSpinner4.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField4ValueChanged, true);
            app.AmplitudeSpinner4.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end