classdef AWGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        M8195AappUIFigure              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConfigTab                      matlab.ui.container.Tab
        PingTestPanel                  matlab.ui.container.Panel
        PingServerButton               matlab.ui.control.Button
        PingTestLamp                   matlab.ui.control.Lamp
        PingTestErrorLabel             matlab.ui.control.Label
        PingAWGPanel                   matlab.ui.container.Panel
        PingAWGButton                  matlab.ui.control.Button
        PingAWGLamp                    matlab.ui.control.Lamp
        PingAWGErrorLabel              matlab.ui.control.Label
        PythonFilesDirEditFieldLabel   matlab.ui.control.Label
        PythonFilesDirEditField        matlab.ui.control.EditField
        LogTab                         matlab.ui.container.Tab
        LogTextArea                    matlab.ui.control.TextArea
        AutoRefreshLogCheckBox         matlab.ui.control.CheckBox
        CustomMessageEditField         matlab.ui.control.EditField
        WriteButton                    matlab.ui.control.Button
        RefreshButton                  matlab.ui.control.Button
        QueryButton                    matlab.ui.control.Button
        UploadTraceTab                 matlab.ui.container.Tab
        LocalFilesPanel                matlab.ui.container.Panel
        uploadLocalErrorConsole        matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        SelectfilesButton              matlab.ui.control.Button
        RemoteFilesPanel               matlab.ui.container.Panel
        Tree                           matlab.ui.container.Tree
        uploadAWGErrorConsole          matlab.ui.control.TextArea
        ChannelSelectionDropDownLabel  matlab.ui.control.Label
        ChannelSelectionDropDown       matlab.ui.control.DropDown
        SendtoAWGButton                matlab.ui.control.Button
        ClockTab                       matlab.ui.container.Tab
        ReferenceclockfrequencyHzSpinnerLabel  matlab.ui.control.Label
        ReferenceclockfrequencyHzSpinner  matlab.ui.control.Spinner
        SamplerateHzSpinnerLabel       matlab.ui.control.Label
        SamplerateHzSpinner            matlab.ui.control.Spinner
        OutputsTab                     matlab.ui.container.Tab
        RunStopButton                  matlab.ui.control.StateButton
        OutputstateCheckBox1           matlab.ui.control.CheckBox
        Channel1Label                  matlab.ui.control.Label
        OutputstateCheckBox2           matlab.ui.control.CheckBox
        Channel2Label                  matlab.ui.control.Label
        OutputstateCheckBox3           matlab.ui.control.CheckBox
        Channel3Label                  matlab.ui.control.Label
        OutputstateCheckBox4           matlab.ui.control.CheckBox
        Channel4Label                  matlab.ui.control.Label
        AmplitudeVLabel                matlab.ui.control.Label
        AmplitudeSpinner1              matlab.ui.control.Spinner
        AmplitudeVLabel_2              matlab.ui.control.Label
        AmplitudeSpinner2              matlab.ui.control.Spinner
        AmplitudeVLabel_3              matlab.ui.control.Label
        AmplitudeSpinner3              matlab.ui.control.Spinner
        AmplitudeVLabel_4              matlab.ui.control.Label
        AmplitudeSpinner4              matlab.ui.control.Spinner
        AutorefreshsettingsCheckBox    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        AWGadd = 'TCPIP0::localhost::inst1::INSTR'; % VISA TCP address of AWG
        OSCadd = 'USB0::0x0957::0x9013::MY50270101::0::INSTR' % VISA TCP address of OSC (PLEASE DOUBLE CHECK)
        interuptTimer = timer();
    end
    
    methods (Access = private)
        
        function refreshStatus(app)% reads current settings from the AWG to updates GUI objects
            % read run/stop status
                %comStr="query.py "+app.AWGadd+" *STB?";
                %[status,response] = callPython(app,comStr)
        
            % read clock status
                % Reference Clock
                comStr="query.py "+app.AWGadd+" :ROSC:FREQ?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return%python error return to prevent freeze
                else
                    app.ReferenceclockfrequencyHzSpinner.Value=str2double(response);
                end
                % Sample Rate
                comStr="query.py "+app.AWGadd+" :FREQ:RAST?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return%python error return to prevent freeze
                else
                    app.SamplerateHzSpinner.Value=str2double(response);
                end
            
            % read output status
                % channel 1 output
                comStr="query.py " + app.AWGadd + " :OUTP1?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                if response(1)=="1"
                    app.OutputstateCheckBox1.Value=1;
                elseif response(1)=="0"
                    app.OutputstateCheckBox1.Value=0;
                end
                
                % channel 2 output
                comStr="query.py " + app.AWGadd + " :OUTP2?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                if response(1)=="1"
                    app.OutputstateCheckBox2.Value=1;
                elseif response(1)=="0"
                    app.OutputstateCheckBox2.Value=0;
                end
                
                % channel 3 output
                comStr="query.py " + app.AWGadd + " :OUTP3?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                if response(1)=="1"
                    app.OutputstateCheckBox3.Value=1;
                elseif response(1)=="0"
                    app.OutputstateCheckBox3.Value=0;
                end
                
                % channel 4 output
                comStr="query.py " + app.AWGadd + " :OUTP4?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                if response(1)=="1"
                    app.OutputstateCheckBox4.Value=1;
                elseif response(1)=="0"
                    app.OutputstateCheckBox4.Value=0;
                end
            
            % read amplitude status
                % channel 1 amplitude
                comStr="query.py " + app.AWGadd + " :VOLT1?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                app.AmplitudeSpinner1.Value=str2double(response);
                
                % channel 2 amplitude
                comStr="query.py " + app.AWGadd + " :VOLT2?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                app.AmplitudeSpinner2.Value=str2double(response);
                
                % channel 3 amplitude
                comStr="query.py " + app.AWGadd + " :VOLT3?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                app.AmplitudeSpinner3.Value=str2double(response);
                
                % channel 4 amplitude
                comStr="query.py " + app.AWGadd + " :VOLT4?";
                [status,response] = callPython(app,comStr);
                drawnow
                if (status)
                    return
                end
                app.AmplitudeSpinner4.Value=str2double(response);
                
        end
        
        function refreshAWGlog(app)
            % clear error buffer
            while (1)
                comStr="query.py " + app.AWGadd + " :SYST:ERR?";
                [status,response]=callPython(app,comStr);
                if status
                    app.LogTextArea.Value=[app.LogTextArea.Value;"connection error: "+response(1:end-2)];
                    break
                end
                if strcmp(response, ['0,"No error"',newline,newline])
                    break
                else
                    app.LogTextArea.Value=[app.LogTextArea.Value;response(1:end-2)];% end-2 to remove new line characters
                end
            end
        end
        
        function [status,cmdOut] = callPython(app,cmdIn)
            % cmdIn = command string (not including python call) e.g.
            %           callPython('pingTest.py')
            cmdStr="cd " + app.PythonFilesDirEditField.Value + " & python " + cmdIn;
            [status,cmdOut] = system(cmdStr);
            
        end
        
        function flag = normalise(app,filePath,fileName)
            data=load([filePath,fileName]);
            normalisationFactor=max(abs(data))+0.0001;
            if normalisationFactor>1
                app.uploadLocalErrorConsole.Value = [app.uploadLocalErrorConsole.Value; ...
                    fileName,' is beyond AWG input bounds normalising by: ',num2str(normalisationFactor,'%2.2f')];   
                drawnow
                data=data./normalisationFactor;
                tempFile=fopen(app.PythonFilesDirEditField.Value+"temp.txt",'wt');
                for val=data'
                    fprintf(tempFile,'%1.8f\n',val);
                end
                fclose(tempFile);
                flag=1;
            else
                flag=0;
            end
        end
        
        function updateCallback(app,~)
            % callback to timer object which runs to update stuff
            % app.PingServerButtonPushed(app);
            if(app.AutoRefreshLogCheckBox.Value)
                refreshAWGlog(app);
            end
            if(app.AutorefreshsettingsCheckBox.Value)
                refreshStatus(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.PythonFilesDirEditField.Value)
                %[~,response]=system('cd');
                s=settings;
                addonPath=s.matlab.addons.InstallationFolder.PersonalValue;
                app.PythonFilesDirEditField.Value=[addonPath,'\Apps\labLAN\'];
            end
            PingServerButtonPushed(app);
            drawnow;
            PingAWGButtonPushed(app);
            drawnow;
            refreshStatus(app);
            drawnow;
            
            % setup background refresh timer
            app.interuptTimer.Period = 10;
            app.interuptTimer.ExecutionMode = 'fixedRate';
            app.interuptTimer.TimerFcn = @(~,~)app.updateCallback; % Here is where you assign the callback function % Description
            start(app.interuptTimer);
        end

        % Button pushed function: PingServerButton
        function PingServerButtonPushed(app, event)
        app.PingTestErrorLabel.Text = "pinging";
        app.PingTestErrorLabel.FontColor=[0.93,0.69,0.13];
        app.PingTestLamp.Color = [0.93,0.69,0.13];
        drawnow
        
       % [~,~] = callPython('cd C:\Users\anton\Documents\FYPrepository\Remotomation\client');
        [status, commandOut] = callPython(app,'pingTest.py');
            if status==2
                uialert(app.M8195AappUIFigure,"python files not found in default addon location, reinstall or point to files in dir bellow","startup error")
            end
            if status==0
                app.PingTestLamp.Color=[0.0,1.0,0.0];
                app.PingTestErrorLabel.FontColor=[0.0,1.0,0.0];
            else
                app.PingTestLamp.Color=[1.0,0.0,0.0];
                app.PingTestErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            app.PingTestErrorLabel.Text = commandOut;
        end

        % Button pushed function: PingAWGButton
        function PingAWGButtonPushed(app, event)
            app.PingAWGErrorLabel.Text = "pinging";
            app.PingAWGErrorLabel.FontColor=[0.93,0.69,0.13];
            app.PingAWGLamp.Color = [0.93,0.69,0.13];
            drawnow
            
            comStr="query.py "+app.AWGadd+" *IDN?";
            [status, commandOut] = callPython(app,comStr);
            
            if status==0
                app.PingAWGLamp.Color=[0.0,1.0,0.0];
                app.PingAWGErrorLabel.FontColor=[0.0,1.0,0.0];
            else
                app.PingAWGLamp.Color=[1.0,0.0,0.0];
                app.PingAWGErrorLabel.FontColor=[1.0,0.0,0.0];
            end
            
            app.PingAWGErrorLabel.Text = commandOut;
        end

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            app.uploadLocalErrorConsole.Value="";
            [files,path] = uigetfile('.txt','Select File(s)','MultiSelect', 'on');
            app.M8195AappUIFigure.Visible = 'off'; % work around for matlab minimising app
            app.M8195AappUIFigure.Visible = 'on'; % idk why this is nessisary
            if path==0 % no files selected/ box closed
                return
            end
            if ~iscell(files)
                files={files};
            end
            % check for spaces in filepath
            
            % loop through selected files
            for file = files
                % check for spaces in filename
                
                app.Label.Text="Uploading "+file;
                drawnow
                normalisationFlag = normalise(app,path,file{1});
                if normalisationFlag
                    comStr="transferFile.py " + app.PythonFilesDirEditField.Value + "temp.txt" + " " + file;
                else
                    comStr="transferFile.py " + path + file + " " + file;
                end
                [status,response] = callPython(app,comStr);
                % check status flag
                if status==0
                    for i=1:length(app.Tree.Children)
                        if strcmp(app.Tree.Children(i).Text,file)
                            app.Tree.Children(i).delete;
                            break
                        end
                    end
                    uitreenode(app.Tree,'Text',file{1});
                    app.uploadLocalErrorConsole.Value = [app.uploadLocalErrorConsole.Value; "Success!"];
                else
                    app.uploadLocalErrorConsole.Value = [app.uploadLocalErrorConsole.Value; string(response)];
                end
            end
            app.Label.Text="";
        end

        % Value changed function: OutputstateCheckBox1
        function OutputstateCheckBox1ValueChanged(app, event)
            value = app.OutputstateCheckBox1.Value;
            if value
                comStr="write.py " + app.AWGadd + " :OUTP1 on";
                [~,~] = callPython(app,comStr);
            else 
                comStr="write.py " + app.AWGadd + " :OUTP1 off";
                [~,~] = callPython(app,comStr);
            end
            %refreshStatus(app);
        end

        % Value changed function: OutputstateCheckBox2
        function OutputstateCheckBox2ValueChanged(app, event)
            value = app.OutputstateCheckBox2.Value;
            if value
                comStr="write.py " + app.AWGadd + " :OUTP2 on";
                [~,~] = callPython(app,comStr);
            else 
                comStr="write.py " + app.AWGadd + " :OUTP2 off";
                [~,~] = callPython(app,comStr);
            end
            %refreshStatus(app);
        end

        % Value changed function: OutputstateCheckBox3
        function OutputstateCheckBox3ValueChanged(app, event)
            value = app.OutputstateCheckBox3.Value;
            if value
                comStr="write.py " + app.AWGadd + " :OUTP3 on";
                [~,~] = callPython(app,comStr);
            else 
                comStr="write.py " + app.AWGadd + " :OUTP3 off";
                [~,~] = callPython(app,comStr);
            end
            %refreshStatus(app);
        end

        % Value changed function: OutputstateCheckBox4
        function OutputstateCheckBox4ValueChanged(app, event)
            value = app.OutputstateCheckBox4.Value;
            if value
                comStr="write.py " + app.AWGadd + " :OUTP4 on";
                [~,~] = callPython(app,comStr);
            else 
                comStr="write.py " + app.AWGadd + " :OUTP4 off";
                [~,~] = callPython(app,comStr);
            end
            %refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner1
        function AmplitudeEditField1ValueChanged(app, event)
            value = app.AmplitudeSpinner1.Value;
            comStr="write.py " + app.AWGadd + " :VOLT1 "+string(value);
            [~,~] = callPython(app,comStr);
            %refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner2
        function AmplitudeEditField2ValueChanged(app, event)
            value = app.AmplitudeSpinner2.Value;
            comStr="write.py " + app.AWGadd + " :VOLT2 "+string(value);
            [~,~] = callPython(app,comStr);
            %refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner3
        function AmplitudeEditField3ValueChanged(app, event)
            value = app.AmplitudeSpinner3.Value;
            comStr="write.py " + app.AWGadd + " :VOLT3 "+string(value);
            [~,~] = callPython(app,comStr);
            %refreshStatus(app);
        end

        % Value changed function: AmplitudeSpinner4
        function AmplitudeEditField4ValueChanged(app, event)
            value = app.AmplitudeSpinner4.Value;
            comStr="write.py " + app.AWGadd + " :VOLT4 "+string(value);
            [~,~] = callPython(app,comStr);
            %refreshStatus(app);
        end

        % Value changed function: ReferenceclockfrequencyHzSpinner
        function ReferenceclockfrequencyHzSpinnerValueChanged(app, event)
            value = app.ReferenceclockfrequencyHzSpinner.Value;
            comStr="write.py "+app.AWGadd+" :ROSC:FREQ "+string(value);
            [~,~] = callPython(app,comStr);
            %refreshStatus(app);
        end

        % Value changed function: SamplerateHzSpinner
        function SamplerateHzSpinnerValueChanged(app, event)
            value = app.SamplerateHzSpinner.Value;
            comStr="write.py "+app.AWGadd+" :FREQ:RAST "+string(value);
            [~,~] = callPython(app,comStr);
            %refreshStatus(app);
        end

        % Value changed function: RunStopButton
        function RunStopButtonValueChanged(app, event)
            value = app.RunStopButton.Value;
            if value
                comStr = "write.py "+app.AWGadd+" :INIT:IMM";
                [~,~] = callPython(app,comStr);
            else
                comStr="write.py "+app.AWGadd+" :ABOR";
                [~,~] = callPython(app,comStr);
            end
            %refreshStatus(app);
        end

        % Callback function
        function stopButtonValueChanged(app, event)
            value = app.stopButton.Value;
            if value
                comStr="write.py "+app.AWGadd+" :ABOR";
                [~,~] = callPython(app,comStr);
            else
                app.RunStopButton.Value=1;
            end
            app.RunStopButton.Value=0;
            %refreshStatus(app);
        end

        % Button pushed function: SendtoAWGButton
        function SendtoAWGButtonPushed(app, event)
            app.uploadAWGErrorConsole.Value="Uploading...";
            if isempty(app.Tree.SelectedNodes)
                app.uploadAWGErrorConsole.Value="UI error: select file from above";
                return
            end
            filename=app.Tree.SelectedNodes.Text;
            drawnow
            refreshAWGlog(app);
            comStr="uploadFile.py " + filename + " " + app.ChannelSelectionDropDown.Value;
            [status,response] = callPython(app,comStr);
            % check status flag
            if status~=0
                app.uploadAWGErrorConsole.Value="server error: " + response;
            else
                comStr="query.py "+app.AWGadd+" :SYST:ERR?";
                [status,response] = callPython(app,comStr);
                if status==0
                    if ~strcmp(response, ['0,"No error"',newline,newline])
                        app.uploadAWGErrorConsole.Value="AWG error: " + response;
                    else
                        app.uploadAWGErrorConsole.Value="Success!";
                    end
                end
            end
            % check error log
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshAWGlog(app);
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            message=app.CustomMessageEditField.Value;
            app.LogTextArea.Value=[app.LogTextArea.Value;">>> " + message];
        
            cmdStr="write.py " + app.AWGadd + " " + message;
            [~,~]=callPython(app,cmdStr);
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            message=app.CustomMessageEditField.Value;
            app.LogTextArea.Value=[app.LogTextArea.Value;">>> " + message];
        
            cmdStr="query.py " + app.AWGadd + " " + message;
            [~,response]=callPython(app,cmdStr);
            app.LogTextArea.Value=[app.LogTextArea.Value;response];
        end

        % Close request function: M8195AappUIFigure
        function M8195AappUIFigureCloseRequest(app, event)
            stop(app.interuptTimer)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.M8195AappUIFigure = uifigure('Visible', 'off');
            app.M8195AappUIFigure.Name = 'M8195A app';
            app.M8195AappUIFigure.CloseRequestFcn = createCallbackFcn(app, @M8195AappUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.M8195AappUIFigure);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.PingTestPanel = uipanel(app.ConfigTab);
            app.PingServerButton = uibutton(app.PingTestPanel, 'push');
            app.PingServerButton.ButtonPushedFcn = createCallbackFcn(app, @PingServerButtonPushed, true);
            app.PingServerButton.Text = 'Ping Server';
            app.PingTestLamp = uilamp(app.PingTestPanel);
            app.PingTestErrorLabel = uilabel(app.PingTestPanel);
            app.PingTestErrorLabel.Text = {''; ''};
            app.PingAWGPanel = uipanel(app.ConfigTab);
            app.PingAWGButton = uibutton(app.PingAWGPanel, 'push');
            app.PingAWGButton.ButtonPushedFcn = createCallbackFcn(app, @PingAWGButtonPushed, true);
            app.PingAWGButton.Text = 'Ping AWG';
            app.PingAWGLamp = uilamp(app.PingAWGPanel);
            app.PingAWGErrorLabel = uilabel(app.PingAWGPanel);
            app.PingAWGErrorLabel.Text = '';
            app.PythonFilesDirEditFieldLabel = uilabel(app.ConfigTab);
            app.PythonFilesDirEditFieldLabel.Text = 'Python Files Dir:';
            app.PythonFilesDirEditField = uieditfield(app.ConfigTab, 'text');
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.LogTextArea.Editable = 'off';
            app.AutoRefreshLogCheckBox = uicheckbox(app.LogTab);
            app.AutoRefreshLogCheckBox.Text = 'Auto Refresh';
            app.AutoRefreshLogCheckBox.Value = true;
            app.CustomMessageEditField = uieditfield(app.LogTab, 'text');
            app.CustomMessageEditField.Value = 'Manual prompt';
            app.WriteButton = uibutton(app.LogTab, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.RefreshButton = uibutton(app.LogTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.QueryButton = uibutton(app.LogTab, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Text = 'Query';
            app.UploadTraceTab = uitab(app.TabGroup);
            app.UploadTraceTab.Title = 'Upload Trace';
            app.LocalFilesPanel = uipanel(app.UploadTraceTab);
            app.LocalFilesPanel.Title = 'Local Files';
            app.uploadLocalErrorConsole = uitextarea(app.LocalFilesPanel);
            app.uploadLocalErrorConsole.Editable = 'off';
            app.Label = uilabel(app.LocalFilesPanel);
            app.Label.Text = '';
            app.SelectfilesButton = uibutton(app.LocalFilesPanel, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select file(s)';
            app.RemoteFilesPanel = uipanel(app.UploadTraceTab);
            app.RemoteFilesPanel.Title = 'Remote Files';
            app.Tree = uitree(app.RemoteFilesPanel);
            app.uploadAWGErrorConsole = uitextarea(app.RemoteFilesPanel);
            app.uploadAWGErrorConsole.Editable = 'off';
            app.ChannelSelectionDropDownLabel = uilabel(app.RemoteFilesPanel);
            app.ChannelSelectionDropDownLabel.Text = 'Channel Selection';
            app.ChannelSelectionDropDown = uidropdown(app.RemoteFilesPanel);
            app.ChannelSelectionDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelSelectionDropDown.Value = '1';
            app.SendtoAWGButton = uibutton(app.RemoteFilesPanel, 'push');
            app.SendtoAWGButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoAWGButtonPushed, true);
            app.SendtoAWGButton.Text = 'Send to AWG';
            app.ClockTab = uitab(app.TabGroup);
            app.ClockTab.Title = 'Clock';
            app.ReferenceclockfrequencyHzSpinnerLabel = uilabel(app.ClockTab);
            app.ReferenceclockfrequencyHzSpinnerLabel.Text = 'Reference clock frequency (Hz)';
            app.ReferenceclockfrequencyHzSpinner = uispinner(app.ClockTab);
            app.ReferenceclockfrequencyHzSpinner.Step = 10000000;
            app.ReferenceclockfrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @ReferenceclockfrequencyHzSpinnerValueChanged, true);
            app.ReferenceclockfrequencyHzSpinner.Value = 100000000;
            app.SamplerateHzSpinnerLabel = uilabel(app.ClockTab);
            app.SamplerateHzSpinnerLabel.Text = 'Sample rate (Hz)';
            app.SamplerateHzSpinner = uispinner(app.ClockTab);
            app.SamplerateHzSpinner.Step = 1000000000;
            app.SamplerateHzSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplerateHzSpinnerValueChanged, true);
            app.SamplerateHzSpinner.Value = 620000;
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.RunStopButton = uibutton(app.OutputsTab, 'state');
            app.RunStopButton.ValueChangedFcn = createCallbackFcn(app, @RunStopButtonValueChanged, true);
            app.RunStopButton.Text = 'Run/Stop';
            app.OutputstateCheckBox1 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox1.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox1ValueChanged, true);
            app.OutputstateCheckBox1.Text = 'Output state';
            app.Channel1Label = uilabel(app.OutputsTab);
            app.Channel1Label.Text = 'Channel 1';
            app.OutputstateCheckBox2 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox2.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox2ValueChanged, true);
            app.OutputstateCheckBox2.Text = 'Output state';
            app.Channel2Label = uilabel(app.OutputsTab);
            app.Channel2Label.Text = 'Channel 2';
            app.OutputstateCheckBox3 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox3.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox3ValueChanged, true);
            app.OutputstateCheckBox3.Text = 'Output state';
            app.Channel3Label = uilabel(app.OutputsTab);
            app.Channel3Label.Text = 'Channel 3';
            app.OutputstateCheckBox4 = uicheckbox(app.OutputsTab);
            app.OutputstateCheckBox4.ValueChangedFcn = createCallbackFcn(app, @OutputstateCheckBox4ValueChanged, true);
            app.OutputstateCheckBox4.Text = 'Output state';
            app.Channel4Label = uilabel(app.OutputsTab);
            app.Channel4Label.Text = 'Channel 4';
            app.AmplitudeVLabel = uilabel(app.OutputsTab);
            app.AmplitudeVLabel.Text = 'Amplitude (V)';
            app.AmplitudeSpinner1 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner1.Step = 0.025;
            app.AmplitudeSpinner1.Limits = [0.075 1];
            app.AmplitudeSpinner1.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField1ValueChanged, true);
            app.AmplitudeSpinner1.Value = 0.5;
            app.AmplitudeVLabel_2 = uilabel(app.OutputsTab);
            app.AmplitudeVLabel_2.Text = 'Amplitude (V)';
            app.AmplitudeSpinner2 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner2.Step = 0.025;
            app.AmplitudeSpinner2.Limits = [0.075 1];
            app.AmplitudeSpinner2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField2ValueChanged, true);
            app.AmplitudeSpinner2.Value = 0.5;
            app.AmplitudeVLabel_3 = uilabel(app.OutputsTab);
            app.AmplitudeVLabel_3.Text = 'Amplitude (V)';
            app.AmplitudeSpinner3 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner3.Step = 0.025;
            app.AmplitudeSpinner3.Limits = [0.075 1];
            app.AmplitudeSpinner3.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField3ValueChanged, true);
            app.AmplitudeSpinner3.Value = 0.5;
            app.AmplitudeVLabel_4 = uilabel(app.OutputsTab);
            app.AmplitudeVLabel_4.Text = 'Amplitude (V)';
            app.AmplitudeSpinner4 = uispinner(app.OutputsTab);
            app.AmplitudeSpinner4.Step = 0.025;
            app.AmplitudeSpinner4.Limits = [0.075 1];
            app.AmplitudeSpinner4.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField4ValueChanged, true);
            app.AmplitudeSpinner4.Value = 0.5;
            app.AutorefreshsettingsCheckBox = uicheckbox(app.OutputsTab);
            app.AutorefreshsettingsCheckBox.Text = 'Auto refresh settings';
            app.AutorefreshsettingsCheckBox.Value = true;
            % Show the figure after all components are created
            app.M8195AappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.M8195AappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.M8195AappUIFigure)
        end
    end
end